classdef bandpassGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileinEditField       matlab.ui.control.EditField
        FileinEditFieldLabel  matlab.ui.control.Label
        BrowseButton          matlab.ui.control.Button
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function process_and_display(app)
            x=0;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FileinEditField
        function FileinEditFieldValueChanged(app, event)
            path = app.FileinEditField.Value;
            %share with browse button?
            %try to load and display image
            image= read_image_file(path);
            %process image and update other displays
            process_and_display();
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            %open file browser
            filter_images= {'*.png;*.jpeg;*.mat'};
            [file, path] = uigetfile(filter_images, 'Select File');
            %TODO handle output 0: user closed file browser!

            %share with FileInEditField
            %try to load and display image
            image = read_image_file([path, filesep, file]);
            %process image and update other displays
            process_and_display();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ZLimitMethod = 'tight';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            app.UIAxes_2.XLimitMethod = 'tight';
            app.UIAxes_2.YLimitMethod = 'tight';
            app.UIAxes_2.ZLimitMethod = 'tight';
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FileinEditFieldLabel = uilabel(app.UIFigure);
            app.FileinEditFieldLabel.Text = 'File in';
            app.FileinEditField = uieditfield(app.UIFigure, 'text');
            app.FileinEditField.ValueChangedFcn = createCallbackFcn(app, @FileinEditFieldValueChanged, true);
            app.FileinEditField.Placeholder = './image_in.jpeg';
            app.FileinEditField.Value = './image_in.jpeg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bandpassGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end